classdef app_with_tunable_params < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StopTimeEditField       matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel  matlab.ui.control.Label
        EditField               matlab.ui.control.NumericEditField
        EditFieldLabel          matlab.ui.control.Label
        Image                   matlab.ui.control.Image
        SaveOutputButton        simulink.ui.control.SaveOutputButton
        Slider                  matlab.ui.control.Slider
        SliderLabel             matlab.ui.control.Label
        TimeScope               matlab.ui.scope.TimeScope
        SimulationControls      simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
            if isinf(value)
                app.Simulation.setModelParameter('StopTime', 'inf');
            else
                app.Simulation.setModelParameter('StopTime', num2str(value));
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.LegendNames = {'Add:1'};
            app.TimeScope.Title = 'Add:1';
            app.TimeScope.YLabel = 'Amplitude';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'amplitude_param';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.Value = 5;
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.UIFigure);
            app.SaveOutputButton.Simulation = app.Simulation;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'TechSource-Logo---Transparent_resized_use_this.png');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'frequency_param';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Value = 6.28318530717959;
            app.StopTimeEditFieldLabel = uilabel(app.UIFigure);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopTimeEditField.Limits = [0 Inf];
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = Inf;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Slider, 'Value', app.Simulation.TunableVariables, 'amplitude_param');
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Add:1'], app.TimeScope);
            bind(app.EditField, 'Value', app.Simulation.TunableVariables, 'frequency_param');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_with_tunable_params

            % Associate the Simulink Model
            app.Simulation = simulation('combined_callback_csv_model_time');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end