classdef controlSimulations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MassSpringDamperUIFigure  matlab.ui.Figure
        StopTimeEditField         matlab.ui.control.EditField
        StopTimeLabel             matlab.ui.control.Label
        Slider                    matlab.ui.control.Slider
        SliderLabel               matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
        LoadInputButton           simulink.ui.control.LoadInputButton
        SaveOutputButton          simulink.ui.control.SaveOutputButton
        Image                     matlab.ui.control.Image
        SimulationProgress        simulink.ui.control.SimulationProgress
        SimulationControls        simulink.ui.control.SimulationControls
        TimeScope                 matlab.ui.scope.TimeScope
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: StopTimeEditField
        function StopTimeEditFieldValueChanged(app, event)
            value = app.StopTimeEditField.Value;
            app.Simulation.setModelParameter('StopTime', num2str(value))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MassSpringDamperUIFigure = uifigure('Visible', 'off');
            app.MassSpringDamperUIFigure.Name = 'Mass Spring Damper';
            app.MassSpringDamperUIFigure.Icon = fullfile(pathToMLAPP, 'TechSource-Logo---Transparent_resized_use_this.png');
            app.TimeScope = uitimescope(app.MassSpringDamperUIFigure);
            app.TimeScope.BufferLength = 500000;
            app.TimeScope.LegendNames = {'Integrator1:1'};
            app.TimeScope.Title = 'Velocity of the Mass';
            app.TimeScope.YLabel = 'Amplitude';
            app.SimulationControls = uisimcontrols(app.MassSpringDamperUIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.MassSpringDamperUIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.Image = uiimage(app.MassSpringDamperUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'massspringdamper_appdes.png');
            app.SaveOutputButton = uisimdatabutton('saveoutput', app.MassSpringDamperUIFigure);
            app.SaveOutputButton.Simulation = app.Simulation;
            app.LoadInputButton = uisimdatabutton('loadinput', app.MassSpringDamperUIFigure);
            app.LoadInputButton.Text = 'Load Input';
            app.LoadInputButton.Simulation = app.Simulation;
            app.Image2 = uiimage(app.MassSpringDamperUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'TechSource-Logo---Transparent_resized_use_this.png');
            app.SliderLabel = uilabel(app.MassSpringDamperUIFigure);
            app.SliderLabel.Text = 'k';
            app.Slider = uislider(app.MassSpringDamperUIFigure);
            app.Slider.Value = 100;
            app.StopTimeLabel = uilabel(app.MassSpringDamperUIFigure);
            app.StopTimeLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.MassSpringDamperUIFigure, 'text');
            app.StopTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StopTimeEditFieldValueChanged, true);
            app.StopTimeEditField.Value = '10';
            % Show the figure after all components are created
            app.MassSpringDamperUIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/Integrator1:1'], app.TimeScope);
            bind(app.Slider, 'Value', app.Simulation.TunableVariables, 'k');
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = controlSimulations

            % Associate the Simulink Model
            app.Simulation = simulation('ex_mass_spring_damper_appdemo');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.MassSpringDamperUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MassSpringDamperUIFigure)
        end
    end
end